classdef pigeoneer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PigeoneerUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        seg_area_ratioEditField       matlab.ui.control.NumericEditField
        seg_area_ratioEditFieldLabel  matlab.ui.control.Label
        feature_treshEditField        matlab.ui.control.NumericEditField
        feature_treshEditFieldLabel   matlab.ui.control.Label
        PigeoneerLabel                matlab.ui.control.Label
        pigeon_treshEditField         matlab.ui.control.NumericEditField
        pigeon_treshEditFieldLabel    matlab.ui.control.Label
        minbboxareaEditField          matlab.ui.control.NumericEditField
        minbboxareaEditFieldLabel     matlab.ui.control.Label
        score_distEditField           matlab.ui.control.NumericEditField
        score_distEditFieldLabel      matlab.ui.control.Label
        OpticalflowCheckBox           matlab.ui.control.CheckBox
        figureselectorDropDown        matlab.ui.control.DropDown
        figureselectorDropDownLabel   matlab.ui.control.Label
        bbox_overlapEditField         matlab.ui.control.NumericEditField
        bbox_overlapEditFieldLabel    matlab.ui.control.Label
        flow_threshEditField          matlab.ui.control.NumericEditField
        flow_threshEditFieldLabel     matlab.ui.control.Label
        valuemaxEditField             matlab.ui.control.NumericEditField
        valuemaxEditFieldLabel        matlab.ui.control.Label
        valueminEditField             matlab.ui.control.NumericEditField
        valueminEditFieldLabel        matlab.ui.control.Label
        saturationmaxEditField        matlab.ui.control.NumericEditField
        saturationmaxEditFieldLabel   matlab.ui.control.Label
        satirationminEditField        matlab.ui.control.NumericEditField
        satirationminEditFieldLabel   matlab.ui.control.Label
        huemaxEditField               matlab.ui.control.NumericEditField
        huemaxEditFieldLabel          matlab.ui.control.Label
        hueminEditField               matlab.ui.control.NumericEditField
        hueminEditFieldLabel          matlab.ui.control.Label
        ExitButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cam = webcam('Microsoft');
        %cam = webcam(1);
        accumelated_features;
        expected_bboxes = [];
        frames_count = [];
        blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, 'CentroidOutputPort', false,'MinimumBlobArea', 5000);
        use_flow;
        opticFlow = opticalFlowFarneback;
        current_fig;
        flow_thesh;
        h_min;
        h_max;
        s_min;
        s_max;
        v_min;
        v_max;
        score_dist;
        pogeon_tresh;
        overlap_ratio;
        min_bbox_area;
        area_ratio;
        feature_count;
        h_frame;
        len_frame;
        sound;
        sound_freq;
        audio_player;
    end
    
    methods (Access = private)
        
        function update_fig(app)
            num_frames_since_sound = 101;
            avg = [];
            while(strcmp(app.StartButton.Text,'Pause'))
                try
                    tic;
                [result,flow, app.expected_bboxes, app.frames_count] = ...
            ProcessFrame(app.cam, app.opticFlow, app.expected_bboxes, app.frames_count,...
            app.blobAnalysis, app.flow_thesh, app.h_frame, app.len_frame, app.score_dist, app.pogeon_tresh, app.current_fig,app.accumelated_features,...
            app.h_min, app.h_max, app.s_min, app.s_max, app.v_min, app.v_max,app.overlap_ratio, app.min_bbox_area, app.area_ratio , app.feature_count);
                time = toc;
                avg = [avg, time];
                catch
                    continue;
                end
                if(strcmp(app.current_fig,'Detector'))
                    value = height(app.expected_bboxes);
                    result = insertText(result,[1 1],string(datetime(now,'ConvertFrom','datenum')),'AnchorPoint','LeftTop');
                    if(value > 0)
                        result = insertText(result,[1 25],append("number of Pigeons:",string(value)),'AnchorPoint','LeftTop','BoxColor', 'red');
                        if(num_frames_since_sound > 100)
                            play(app.audio_player);
                            num_frames_since_sound = 0;
                        end
                    else
                        result = insertText(result,[1 25],append("number of Pigeons:",string(value)),'AnchorPoint','LeftTop','BoxColor', 'green');
                    end
                    result = insertText(result,[1 50],append("avg time per frame: ", string(mean(avg))),'AnchorPoint','LeftTop','BoxColor', 'green');
                    num_frames_since_sound = num_frames_since_sound + 1;
                end
                
                imshow(result,'Parent',app.UIAxes);
                if(numel(avg) > 100)
                    avg = [];
                end
                
                if(app.use_flow)
                    hold(app.UIAxes,'on')
                    plot(flow,'DecimationFactor',[10 10],'ScaleFactor',1,'Parent',app.UIAxes);
                    hold(app.UIAxes,'off')
                end
                
                pause(1e-3);
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.cam.Resolution = '640x360';
                app.cam.WhiteBalanceMode = 'manual';
                app.cam.ExposureMode = 'manual';
                app.cam.Exposure = -7;
                app.cam.Saturation = 50;
                app.cam.Sharpness = 50;
                app.cam.Brightness = 50;


                load('extracted_features.mat','accumelated_features');

                app.accumelated_features = accumelated_features;
                app.flow_thesh =  app.flow_threshEditField.Value;
                app.h_min = app.hueminEditField.Value;
                app.h_max = app.huemaxEditField.Value; 
                app.s_min = app.satirationminEditField.Value;
                app.s_max = app.saturationmaxEditField.Value;
                app.v_min = app.valueminEditField.Value;
                app.v_max = app.valuemaxEditField.Value;
                app.score_dist = app.score_distEditField.Value;
                app.overlap_ratio = app.bbox_overlapEditField.Value;
                app.pogeon_tresh = app.pigeon_treshEditField.Value;
                app.current_fig = app.figureselectorDropDown.Value; 
                app.min_bbox_area = app.minbboxareaEditField.Value;
                app.area_ratio = app.seg_area_ratioEditField.Value;
                app.feature_count = app.feature_treshEditField.Value;
                [app.sound, app.sound_freq] = audioread("soundfile.mp3");
                app.audio_player = audioplayer(app.sound, app.sound_freq);
                rgb_frame = snapshot(app.cam);
                frame = rgb2gray(rgb_frame);
                [app.h_frame, app.len_frame] = size(frame);
                axis(app.UIAxes,[1 app.len_frame 1 app.h_frame])

        end

        % Value changed function: figureselectorDropDown
        function figureselectorDropDownValueChanged(app, event)
            app.current_fig = app.figureselectorDropDown.Value;
            title(app.UIAxes, app.figureselectorDropDown.Value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            isTextStart = strcmp(app.StartButton.Text,'Start');
            isTextCont = strcmp(app.StartButton.Text,'Continue');

            if (isTextStart || isTextCont)
                    app.StartButton.Text= 'Pause';
            else
                    app.StartButton.Text = 'Continue';
            end
            try
            update_fig(app);
            catch ME
                disp(ME.identifier);
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            clear app.cam;
            delete(app);
        end

        % Value changed function: hueminEditField
        function hueminEditFieldValueChanged(app, event)
            app.h_min = app.hueminEditField.Value;
            
        end

        % Value changed function: huemaxEditField
        function huemaxEditFieldValueChanged(app, event)
            app.h_max = app.huemaxEditField.Value;
            
        end

        % Value changed function: satirationminEditField
        function satirationminEditFieldValueChanged(app, event)
            app.s_min = app.satirationminEditField.Value;
            
        end

        % Value changed function: saturationmaxEditField
        function saturationmaxEditFieldValueChanged(app, event)
            app.s_max = app.saturationmaxEditField.Value;
            
        end

        % Value changed function: valueminEditField
        function valueminEditFieldValueChanged(app, event)
            app.v_min = app.valueminEditField.Value;
            
        end

        % Value changed function: valuemaxEditField
        function valuemaxEditFieldValueChanged(app, event)
            app.v_max = app.valuemaxEditField.Value;
            
        end

        % Value changed function: flow_threshEditField
        function flow_threshEditFieldValueChanged(app, event)
            app.flow_thesh = app.flow_threshEditField.Value;
            
        end

        % Value changed function: bbox_overlapEditField
        function bbox_overlapEditFieldValueChanged(app, event)
            app.overlap_ratio = app.bbox_overlapEditField.Value;
            
        end

        % Value changed function: score_distEditField
        function score_distEditFieldValueChanged(app, event)
            app.score_dist = app.score_distEditField.Value;
            
        end

        % Value changed function: minbboxareaEditField
        function minbboxareaEditFieldValueChanged(app, event)
            app.min_bbox_area = app.minbboxareaEditField.Value;
            app.blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, 'CentroidOutputPort', false,'MinimumBlobArea', app.min_bbox_area);
            
        end

        % Value changed function: pigeon_treshEditField
        function pigeon_treshEditFieldValueChanged(app, event)
            app.pogeon_tresh = app.pigeon_treshEditField.Value;
            
        end

        % Value changed function: OpticalflowCheckBox
        function OpticalflowCheckBoxValueChanged(app, event)
            app.use_flow = app.OpticalflowCheckBox.Value;
            
        end

        % Value changed function: seg_area_ratioEditField
        function seg_area_ratioEditFieldValueChanged(app, event)
            app.area_ratio = app.seg_area_ratioEditField.Value;
            
        end

        % Value changed function: feature_treshEditField
        function feature_treshEditFieldValueChanged(app, event)
            app.feature_count = app.feature_treshEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PigeoneerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {410, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PigeoneerUIFigure = uifigure('Visible', 'off');
            app.PigeoneerUIFigure.Name = 'Pigeoneer';
            app.PigeoneerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PigeoneerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.LeftPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.hueminEditFieldLabel = uilabel(app.LeftPanel);
            app.hueminEditFieldLabel.Text = 'hue min';
            app.hueminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.hueminEditField.Limits = [0 255];
            app.hueminEditField.ValueChangedFcn = createCallbackFcn(app, @hueminEditFieldValueChanged, true);
            app.hueminEditField.Value = 105;
            app.huemaxEditFieldLabel = uilabel(app.LeftPanel);
            app.huemaxEditFieldLabel.Text = 'hue max';
            app.huemaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.huemaxEditField.Limits = [0 255];
            app.huemaxEditField.ValueChangedFcn = createCallbackFcn(app, @huemaxEditFieldValueChanged, true);
            app.huemaxEditField.Value = 140;
            app.satirationminEditFieldLabel = uilabel(app.LeftPanel);
            app.satirationminEditFieldLabel.Text = 'satiration min';
            app.satirationminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.satirationminEditField.Limits = [0 255];
            app.satirationminEditField.ValueChangedFcn = createCallbackFcn(app, @satirationminEditFieldValueChanged, true);
            app.satirationminEditField.Value = 5;
            app.saturationmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.saturationmaxEditFieldLabel.Text = 'saturation max';
            app.saturationmaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.saturationmaxEditField.Limits = [0 255];
            app.saturationmaxEditField.ValueChangedFcn = createCallbackFcn(app, @saturationmaxEditFieldValueChanged, true);
            app.saturationmaxEditField.Value = 36;
            app.valueminEditFieldLabel = uilabel(app.LeftPanel);
            app.valueminEditFieldLabel.Text = 'value min';
            app.valueminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.valueminEditField.Limits = [0 255];
            app.valueminEditField.ValueChangedFcn = createCallbackFcn(app, @valueminEditFieldValueChanged, true);
            app.valueminEditField.Value = 179;
            app.valuemaxEditFieldLabel = uilabel(app.LeftPanel);
            app.valuemaxEditFieldLabel.Text = 'value max';
            app.valuemaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.valuemaxEditField.Limits = [0 255];
            app.valuemaxEditField.ValueChangedFcn = createCallbackFcn(app, @valuemaxEditFieldValueChanged, true);
            app.valuemaxEditField.Value = 255;
            app.flow_threshEditFieldLabel = uilabel(app.LeftPanel);
            app.flow_threshEditFieldLabel.Text = 'flow_thresh';
            app.flow_threshEditField = uieditfield(app.LeftPanel, 'numeric');
            app.flow_threshEditField.Limits = [0 255];
            app.flow_threshEditField.ValueChangedFcn = createCallbackFcn(app, @flow_threshEditFieldValueChanged, true);
            app.flow_threshEditField.Value = 3.5;
            app.bbox_overlapEditFieldLabel = uilabel(app.LeftPanel);
            app.bbox_overlapEditFieldLabel.Text = 'bbox_overlap';
            app.bbox_overlapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bbox_overlapEditField.Limits = [0 1];
            app.bbox_overlapEditField.ValueChangedFcn = createCallbackFcn(app, @bbox_overlapEditFieldValueChanged, true);
            app.bbox_overlapEditField.Value = 0.5;
            app.figureselectorDropDownLabel = uilabel(app.LeftPanel);
            app.figureselectorDropDownLabel.Text = 'figure selector';
            app.figureselectorDropDown = uidropdown(app.LeftPanel);
            app.figureselectorDropDown.Items = {'Detector', 'Movement', 'Mask', 'Color Segmentation'};
            app.figureselectorDropDown.ValueChangedFcn = createCallbackFcn(app, @figureselectorDropDownValueChanged, true);
            app.figureselectorDropDown.Value = 'Detector';
            app.OpticalflowCheckBox = uicheckbox(app.LeftPanel);
            app.OpticalflowCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpticalflowCheckBoxValueChanged, true);
            app.OpticalflowCheckBox.Text = 'Optical flow';
            app.score_distEditFieldLabel = uilabel(app.LeftPanel);
            app.score_distEditFieldLabel.Text = 'score_dist';
            app.score_distEditField = uieditfield(app.LeftPanel, 'numeric');
            app.score_distEditField.ValueChangedFcn = createCallbackFcn(app, @score_distEditFieldValueChanged, true);
            app.score_distEditField.Value = 0.5;
            app.minbboxareaEditFieldLabel = uilabel(app.LeftPanel);
            app.minbboxareaEditFieldLabel.Text = 'min bbox area';
            app.minbboxareaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.minbboxareaEditField.Limits = [0 20000];
            app.minbboxareaEditField.ValueChangedFcn = createCallbackFcn(app, @minbboxareaEditFieldValueChanged, true);
            app.minbboxareaEditField.Value = 1000;
            app.pigeon_treshEditFieldLabel = uilabel(app.LeftPanel);
            app.pigeon_treshEditFieldLabel.Text = 'pigeon_tresh';
            app.pigeon_treshEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pigeon_treshEditField.Limits = [0 1];
            app.pigeon_treshEditField.ValueChangedFcn = createCallbackFcn(app, @pigeon_treshEditFieldValueChanged, true);
            app.pigeon_treshEditField.Value = 0.4;
            app.PigeoneerLabel = uilabel(app.LeftPanel);
            app.PigeoneerLabel.Text = 'Pigeoneer';
            app.feature_treshEditFieldLabel = uilabel(app.LeftPanel);
            app.feature_treshEditFieldLabel.Text = 'feature_tresh';
            app.feature_treshEditField = uieditfield(app.LeftPanel, 'numeric');
            app.feature_treshEditField.Limits = [0 20];
            app.feature_treshEditField.ValueChangedFcn = createCallbackFcn(app, @feature_treshEditFieldValueChanged, true);
            app.feature_treshEditField.Value = 10;
            app.seg_area_ratioEditFieldLabel = uilabel(app.LeftPanel);
            app.seg_area_ratioEditFieldLabel.Text = 'seg_area_ratio';
            app.seg_area_ratioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.seg_area_ratioEditField.Limits = [0 1];
            app.seg_area_ratioEditField.ValueChangedFcn = createCallbackFcn(app, @seg_area_ratioEditFieldValueChanged, true);
            app.seg_area_ratioEditField.Value = 0.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Detector')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.PigeoneerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pigeoneer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PigeoneerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PigeoneerUIFigure)
        end
    end
end